classdef second_order_system_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        TfinalEditField      matlab.ui.control.NumericEditField
        TfinalLabel          matlab.ui.control.Label
        byNJUPTLiuLabel      matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Tab_2                matlab.ui.container.Tab
        sigmaEditField       matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
        omega_dEditField     matlab.ui.control.NumericEditField
        omega_dLabel         matlab.ui.control.Label
        betaEditField        matlab.ui.control.NumericEditField
        betaEditFieldLabel   matlab.ui.control.Label
        Tab                  matlab.ui.container.Tab
        UITable_step         matlab.ui.control.Table
        Tab_performance      matlab.ui.container.Tab
        UITable_performance  matlab.ui.control.Table
        PhisfracCsRsfracomega_n2s22xiomega_nsomega_n2Label  matlab.ui.control.Label
        zetaSlider           matlab.ui.control.Slider
        xiSliderLabel        matlab.ui.control.Label
        omega_nSlider        matlab.ui.control.Slider
        omega_nLabel         matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        omega_n % 自然频率
        zeta % 阻尼比
        beta % 阻尼角
        omega_d % 阻尼震荡角频率
        sigma % 衰减系数
        model % 闭环传递函数
    end
    
    methods (Access = private)
        
        function updateSystem(app)
            app.beta=acos(app.zeta);
            app.betaEditField.Value=isreal(app.beta)*app.beta;
            
            app.omega_d=app.omega_n*sqrt(1-app.zeta^2);
            app.omega_dEditField.Value=abs(app.omega_d);
            
            app.sigma=app.omega_n*app.zeta;
            app.sigmaEditField.Value=app.sigma;
            
            app.model=tf(app.omega_n^2,[1 2*app.zeta*app.omega_n app.omega_n^2]);

            %绘制零极点图
            ps=[app.sigma+1j*app.omega_d+0j,app.sigma-1j*app.omega_d+0j];            
            plot(app.UIAxes,real(ps),imag(ps),'x');
            
            Tfinal=app.TfinalEditField.Value;
            [u,t] = gensig("sine",10,Tfinal);
            [y,t]=lsim(app.model,u,t);
            
            plot(app.UIAxes6,t,y,t,u);
            [y,t]=impulse(app.model,Tfinal);
            plot(app.UIAxes2,t,y,t,zeros(size(t)));
        
            [y,t]=step(app.model,Tfinal);
            plot(app.UIAxes3,t,y,t,ones(size(t)));
            info1=stepinfo(app.model);
            app.UITable_step.Data=struct2table(info1);
            
            t=0:0.01:Tfinal;
            u=t;
            y=lsim(app.model,u,t);
            plot(app.UIAxes4,t,y,t,u);
            info2=lsiminfo(y,t);
            
            t=0:0.01:Tfinal;
            u=1/2*t.^2;
            [y,t]=lsim(app.model,u,t);
            plot(app.UIAxes5,t,y,t,u);
            info3=lsiminfo(y,t);
            app.UITable_performance.Data=struct2table([info2,info3]);
            app.UITable_performance.ColumnName=fieldnames(info3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.zeta=app.zetaSlider.Value;
            app.omega_n=app.omega_nSlider.Value;
            app.updateSystem();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: omega_nSlider
        function omega_nSliderValueChanging(app, event)
            changingValue = event.Value;
            app.omega_n=changingValue;
            app.updateSystem();
        end

        % Value changing function: zetaSlider
        function zetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.zeta=changingValue;
            app.updateSystem();
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.omega_nLabel = uilabel(app.LeftPanel);
            app.omega_nLabel.Interpreter = 'latex';
            app.omega_nLabel.Text = {'自然频率'; '$\omega_n$'};
            app.omega_nSlider = uislider(app.LeftPanel);
            app.omega_nSlider.Limits = [0 2];
            app.omega_nSlider.ValueChangingFcn = createCallbackFcn(app, @omega_nSliderValueChanging, true);
            app.omega_nSlider.Value = 1;
            app.xiSliderLabel = uilabel(app.LeftPanel);
            app.xiSliderLabel.Interpreter = 'latex';
            app.xiSliderLabel.Text = {'阻尼比'; '$\xi$'};
            app.zetaSlider = uislider(app.LeftPanel);
            app.zetaSlider.Limits = [-2 2];
            app.zetaSlider.ValueChangingFcn = createCallbackFcn(app, @zetaSliderValueChanging, true);
            app.PhisfracCsRsfracomega_n2s22xiomega_nsomega_n2Label = uilabel(app.LeftPanel);
            app.PhisfracCsRsfracomega_n2s22xiomega_nsomega_n2Label.Interpreter = 'latex';
            app.PhisfracCsRsfracomega_n2s22xiomega_nsomega_n2Label.Text = {'二阶系统动态性能分析'; '$\Phi(s)=\frac{C(s)}{R(s)}=\frac{\omega_n^2}{s^2+2\xi \omega_n s+\omega_n^2}$'};
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '基本';
            app.betaEditFieldLabel = uilabel(app.Tab_2);
            app.betaEditFieldLabel.Interpreter = 'latex';
            app.betaEditFieldLabel.Text = '阻尼角$\beta$';
            app.betaEditField = uieditfield(app.Tab_2, 'numeric');
            app.omega_dLabel = uilabel(app.Tab_2);
            app.omega_dLabel.Interpreter = 'latex';
            app.omega_dLabel.Text = {'阻尼振荡'; '角频率$\omega_d$'};
            app.omega_dEditField = uieditfield(app.Tab_2, 'numeric');
            app.sigmaEditFieldLabel = uilabel(app.Tab_2);
            app.sigmaEditFieldLabel.Interpreter = 'latex';
            app.sigmaEditFieldLabel.Text = '衰减系数$\sigma$';
            app.sigmaEditField = uieditfield(app.Tab_2, 'numeric');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '阶跃响应性能';
            app.UITable_step = uitable(app.Tab);
            app.UITable_step.ColumnName = {'上升时间'; '调节时间'; 'SettlingMin'; 'SettlingMax'; '超调量'; 'Undershoot'; 'Peak'; '峰值时间'; ''};
            app.UITable_step.RowName = {};
            app.Tab_performance = uitab(app.TabGroup);
            app.Tab_performance.Title = '响应性能';
            app.UITable_performance = uitable(app.Tab_performance);
            app.UITable_performance.ColumnName = '';
            app.UITable_performance.RowName = {'速度响应,'; '加速度响应'};
            app.byNJUPTLiuLabel = uilabel(app.LeftPanel);
            app.byNJUPTLiuLabel.Interpreter = 'latex';
            app.byNJUPTLiuLabel.Text = 'by NJUPT $Liu$';
            app.TfinalLabel = uilabel(app.LeftPanel);
            app.TfinalLabel.Text = '仿真时长Tfinal';
            app.TfinalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TfinalEditField.Limits = [0 Inf];
            app.TfinalEditField.ValueDisplayFormat = '%d';
            app.TfinalEditField.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '零极点图')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.CLim = [-1 1];
            app.UIAxes.ALim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, '单位脉冲响应')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, '单位阶跃响应')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, '单位速度响应')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, '单位加速度响应')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, '正弦输入响应')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = second_order_system_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end